classdef dnme < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        segmentButton     matlab.ui.control.Button
        dyEditField       matlab.ui.control.NumericEditField
        dyEditFieldLabel  matlab.ui.control.Label
        dxEditField       matlab.ui.control.NumericEditField
        dxEditFieldLabel  matlab.ui.control.Label
        initializeButton  matlab.ui.control.Button
        RightPanel        matlab.ui.container.Panel
        Slider_y          matlab.ui.control.Slider
        Slider_yLabel     matlab.ui.control.Label
        Slider_x          matlab.ui.control.Slider
        Slider_xLabel     matlab.ui.control.Label
        zoomoutCheckBox   matlab.ui.control.CheckBox
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        value1  = 60 % Description
        value2  = 40
        carry
        valuex = 1
        valuey = 1
        buffer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: initializeButton
        function initializeButtonPushed(app, event)
            %a = rand(5,6);


            uiopen('*.mat');
            app.carry = data;


            %b = data > app.value/100;
            %im2 = imagesc(app.UIAxes2,max(data,5*b));


            % axis equal tight
        end

        % Value changed function: dxEditField
        function dxEditFieldValueChanged(app, event)
            app.value1 = app.dxEditField.Value;
            

        end

        % Value changed function: dyEditField
        function dyEditFieldValueChanged(app, event)
            app.value2 = app.dyEditField.Value;
            
        end

        % Button pushed function: segmentButton
        function segmentButtonPushed(app, event)

            app.buffer = app.carry(app.valuex*app.value1:(app.valuex+1)*app.value1-1 , app.valuey*app.value2:(app.valuey+1)*app.value2-1);
            
            imagesc(app.buffer);
            [x,y] = ginput
            round(x);
            round(y);


        end

        % Value changed function: Slider_y
        function Slider_yValueChanged(app, event)
            app.valuex = round(app.Slider_y.Value); 
            app.buffer = app.carry(app.valuex*app.value1:(app.valuex+1)*app.value1-1 , app.valuey*app.value2:(app.valuey+1)*app.value2-1);

            im1 = imagesc(app.UIAxes,app.buffer);
            im1.AlphaData = 0.85;
        end

        % Value changed function: Slider_x
        function Slider_xValueChanged(app, event)
            app.valuey = round(app.Slider_x.Value);
            app.buffer = app.carry(app.valuex*app.value1:(app.valuex+1)*app.value1-1 , app.valuey*app.value2:(app.valuey+1)*app.value2-1);

            im1 = imagesc(app.UIAxes,app.buffer);
            im1.AlphaData = 0.85;
            
        end

        % Value changed function: zoomoutCheckBox
        function zoomoutCheckBoxValueChanged(app, event)
            val = app.zoomoutCheckBox.Value;
            
            if val == 1
                im1 = imagesc(app.UIAxes,app.carry);
                im1.AlphaData = 0.85;
                segment = zeros(size(app.carry));
                segment(99:120,79:99) = 1;
                im2 = imagesc(app.UIAxes2, segment);
                im2.AlphaData =0.85;
                

            else
                
                im1 = imagesc(app.UIAxes,app.buffer);
                
                im1.AlphaData = 0.85;
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {230, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.initializeButton = uibutton(app.LeftPanel, 'push');
            app.initializeButton.ButtonPushedFcn = createCallbackFcn(app, @initializeButtonPushed, true);
            app.initializeButton.Text = 'initialize';
            app.dxEditFieldLabel = uilabel(app.LeftPanel);
            app.dxEditFieldLabel.Text = 'dx';
            app.dxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dxEditField.Limits = [20 2000];
            app.dxEditField.RoundFractionalValues = 'on';
            app.dxEditField.ValueChangedFcn = createCallbackFcn(app, @dxEditFieldValueChanged, true);
            app.dxEditField.Value = 40;
            app.dyEditFieldLabel = uilabel(app.LeftPanel);
            app.dyEditFieldLabel.Text = 'dy';
            app.dyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dyEditField.Limits = [20 1600];
            app.dyEditField.ValueChangedFcn = createCallbackFcn(app, @dyEditFieldValueChanged, true);
            app.dyEditField.Value = 30;
            app.segmentButton = uibutton(app.LeftPanel, 'push');
            app.segmentButton.ButtonPushedFcn = createCallbackFcn(app, @segmentButtonPushed, true);
            app.segmentButton.Text = 'segment';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'raw')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'segmented')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.zoomoutCheckBox = uicheckbox(app.RightPanel);
            app.zoomoutCheckBox.ValueChangedFcn = createCallbackFcn(app, @zoomoutCheckBoxValueChanged, true);
            app.zoomoutCheckBox.Text = 'zoom out';
            app.Slider_xLabel = uilabel(app.RightPanel);
            app.Slider_xLabel.Text = 'Slider_x';
            app.Slider_x = uislider(app.RightPanel);
            app.Slider_x.Limits = [0.001 10];
            app.Slider_x.MajorTicks = [0 2 4 6 8 10];
            app.Slider_x.MajorTickLabels = {'0', '2', '4', '6', '8', '10'};
            app.Slider_x.ValueChangedFcn = createCallbackFcn(app, @Slider_xValueChanged, true);
            app.Slider_x.Value = 1;
            app.Slider_yLabel = uilabel(app.RightPanel);
            app.Slider_yLabel.Text = 'Slider_y';
            app.Slider_y = uislider(app.RightPanel);
            app.Slider_y.Limits = [0.001 10];
            app.Slider_y.MajorTicks = [2 4 6 8 10];
            app.Slider_y.MajorTickLabels = {'2', '4', '6', '8', '10'};
            app.Slider_y.ValueChangedFcn = createCallbackFcn(app, @Slider_yValueChanged, true);
            app.Slider_y.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dnme

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end