classdef segment_v1_nomaxclmn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        maxcolumnEditField  matlab.ui.control.NumericEditField
        mainButton          matlab.ui.control.Button
        nextButton          matlab.ui.control.Button
        prevButton          matlab.ui.control.Button
        SelectFileLabel     matlab.ui.control.Label
        defaultwithButton   matlab.ui.control.Button
        EditField           matlab.ui.control.NumericEditField
        pageEditField       matlab.ui.control.NumericEditField
        pageEditFieldLabel  matlab.ui.control.Label
        maxpageEditField    matlab.ui.control.NumericEditField
        Label_4             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        maxrowEditField     matlab.ui.control.NumericEditField
        saveButton          matlab.ui.control.Button
        dyEditField         matlab.ui.control.NumericEditField
        rowEditField        matlab.ui.control.NumericEditField
        rowLabel            matlab.ui.control.Label
        columnEditField     matlab.ui.control.NumericEditField
        columnLabel         matlab.ui.control.Label
        dyEditFieldLabel    matlab.ui.control.Label
        dxEditField_2       matlab.ui.control.NumericEditField
        dxEditField_2Label  matlab.ui.control.Label
        initButton          matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        value1  = 800
        value2  = 125
        carry
        a = -1
        amax = 1
        b = 0
        bmax = 1
        sizeof
        buffer
        seg
        segbuff
        coord1 = [0 0]
        coord1seg = [0 0]
        coord2 = [0 0]
        coord2seg = [0 0]
        dummy = 0
        file
        path
        minsnr = 20
        savedir
        filedir
        axis1

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: initButton
        function initButtonPushed(app, event)


            app.carry = [];
            app.a = -1;
            app.amax = 1;
            app.b = 0;
            app.bmax = 1;
            app.sizeof = [];
            app.buffer = [];
            app.seg = [];
            app.segbuff = [];
            app.coord1 = [0 0];
            app.coord1seg = [0 0];
            app.coord2 = [0 0];
            app.coord2seg = [0 0];
            app.dummy = 0;
            app.file = [];
            app.path = [];
            app.minsnr = 20;
            app.savedir = [];
            app.filedir = [];


            [app.file, app.path] = uigetfile('*.mat', 'Select a file', 'C:\Users\STAJYER\Desktop\images\specs\');


            if isequal(app.file,0)
                app.SelectFileLabel.Text = 'No file selected';
            else
                lenfile = length(app.file);
                app.savedir = append("C:\Users\STAJYER\Desktop\Yeni klasör\deneme\binary\", app.file(1:lenfile-4), 'bin', '.mat');
                %lenpath = length(app.path);
                %app.savedir = append(app.path(1:lenpath - 6), 'binary\', app.file(1:lenfile-4), 'bnr', '.mat');
                app.filedir = append("C:\Users\STAJYER\Desktop\Yeni klasör\deneme\SPECS\", app.file);


                if  exist(app.savedir,'file') == 2
                    dataname = fullfile(app.path,app.file);
                    image = load(dataname);
                    app.carry = image.P;
                    app.SelectFileLabel.Text = ['Already exists ', app.file];
                    saved = load(app.savedir);
                    app.seg = saved.p;

                else

                    dataname = fullfile(app.path,app.file);
                    image = load(dataname);
                    app.carry = image.P;
                    app.SelectFileLabel.Text = ['Selected file is ', app.file];
                    app.seg = zeros(size(app.carry));

                end


            end

            app.sizeof = size(app.carry);
            app.amax = floor((app.sizeof(1,1)-1)/app.value1);
            app.bmax = floor((app.sizeof(1,2)-1)/app.value2);


            app.maxrowEditField.Value = app.amax + 1;
            app.maxcolumnEditField.Value = app.bmax + 1;
            app.maxpageEditField.Value = (app.bmax + 1)*(app.amax + 1);


            imagesc(app.UIAxes2, app.seg);
            axis(app.UIAxes2, "tight");

            imagesc(app.UIAxes,app.carry);
            axis(app.UIAxes, "tight");
            linkaxes([app.UIAxes app.UIAxes2], 'xy')
            colorbar(app.UIAxes2)
            colorbar(app.UIAxes)


        end

        % Button pushed function: prevButton
        function prevButtonPushed(app, event)


            app.SelectFileLabel.Text = 'previous page';

            app.a = app.a - 1;
            app.dummy = 0;

            if app.a >= 0

                if app.b < app.bmax
                    app.buffer = app.carry(app.a*app.value1+1:(app.a+1)*app.value1, app.b*app.value2+1:(app.b+1)*app.value2);
                    app.segbuff = app.seg(app.a*app.value1+1:(app.a+1)*app.value1, app.b*app.value2+1:(app.b+1)*app.value2);
                else
                    if app.a ==app.amax
                        app.buffer = app.carry(app.a*app.value1+1:end, app.b*app.value2+1:end);
                        app.segbuff = app.seg(app.a*app.value1+1:end, app.b*app.value2+1:end);
                    else
                        app.buffer = app.carry(app.a*app.value1+1:(app.a+1)*app.value1, app.b*app.value2+1:end);
                        app.segbuff = app.seg(app.a*app.value1+1:(app.a+1)*app.value1, app.b*app.value2+1:end);
                    end
                end

                imagesc(app.UIAxes,app.buffer);
                axis(app.UIAxes, "tight");
                imagesc(app.UIAxes2,app.segbuff);
                axis(app.UIAxes2, "tight");
            end
            if app.a < 0
                if app.b > 0
                    app.b = app.b - 1;
                    app.a = app.amax;
                    app.buffer = app.carry(app.a*app.value1+1:end , app.b*app.value2+1:(app.b+1)*app.value2);
                    app.segbuff = app.seg(app.a*app.value1+1:end , app.b*app.value2+1:(app.b+1)*app.value2);
                    imagesc(app.UIAxes,app.buffer);
                    axis(app.UIAxes, "tight");
                    imagesc(app.UIAxes2,app.segbuff);
                    axis(app.UIAxes2, "tight");

                end
            end

            if app.b == 0 && app.a < 0

                app.b = 0;
                app.a = -1;
                imagesc(app.UIAxes,app.carry);
                axis(app.UIAxes, "tight");
                imagesc(app.UIAxes2,app.seg);
                axis(app.UIAxes2, "tight");
            end

            app.rowEditField.Value = app.a + 1;
            app.columnEditField.Value = app.b + 1;
            app.pageEditField.Value = app.b*(app.amax +1) + app.a + 1;

        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)

            app.SelectFileLabel.Text = 'next page';


            app.a = app.a + 1;
            app.dummy = 0;

            if app.a < app.amax


                if app.b < app.bmax
                    app.buffer = app.carry(app.a*app.value1+1:(app.a+1)*app.value1, app.b*app.value2+1:(app.b+1)*app.value2);
                    app.segbuff = app.seg(app.a*app.value1+1:(app.a+1)*app.value1, app.b*app.value2+1:(app.b+1)*app.value2);
                else
                    app.buffer = app.carry(app.a*app.value1+1:(app.a+1)*app.value1, app.b*app.value2+1:end);
                    app.segbuff = app.seg(app.a*app.value1+1:(app.a+1)*app.value1, app.b*app.value2+1:end);
                end
                imagesc(app.UIAxes,app.buffer);
                axis(app.UIAxes, "tight");
                imagesc(app.UIAxes2,app.segbuff);
                axis(app.UIAxes2, "tight");
            end

            if app.a == app.amax

                if app.b < app.bmax
                    app.buffer = app.carry(app.a*app.value1+1:end, app.b*app.value2+1:(app.b+1)*app.value2);
                    app.segbuff = app.seg(app.a*app.value1+1:end, app.b*app.value2+1:(app.b+1)*app.value2);
                else
                    app.buffer = app.carry(app.a*app.value1+1:end, app.b*app.value2+1:end);
                    app.segbuff = app.seg(app.a*app.value1+1:end, app.b*app.value2+1:end);
                end

                imagesc(app.UIAxes,app.buffer);
                axis(app.UIAxes, "tight");
                imagesc(app.UIAxes2,app.segbuff);
                axis(app.UIAxes2, "tight");
            end
            if app.a > app.amax
                app.b = app.b + 1;
                if app.b < app.bmax

                    app.a = 0;

                    app.buffer = app.carry(1:app.value1, app.b*app.value2+1:(app.b+1)*app.value2);
                    app.segbuff = app.seg(1:app.value1, app.b*app.value2+1:(app.b+1)*app.value2);

                    imagesc(app.UIAxes,app.buffer);
                    axis(app.UIAxes, "tight");
                    imagesc(app.UIAxes2,app.segbuff);
                    axis(app.UIAxes2, "tight");
                end
                if app.b == app.bmax

                    app.a = 0;

                    app.buffer = app.carry(1:app.value1, app.b*app.value2+1:end);
                    app.segbuff = app.seg(1:app.value1, app.b*app.value2+1:end);
                    imagesc(app.UIAxes,app.buffer);
                    axis(app.UIAxes, "tight");
                    imagesc(app.UIAxes2,app.segbuff);
                    axis(app.UIAxes2, "tight");
                end

                if app.b > app.bmax
                    app.b = app.bmax;
                    app.a = app.amax + 1;
                    imagesc(app.UIAxes,app.carry);
                    axis(app.UIAxes, "tight");
                    imagesc(app.UIAxes2,app.seg);
                    axis(app.UIAxes2, "tight");
                end

            end
            app.rowEditField.Value = app.a + 1;
            app.columnEditField.Value = app.b + 1;
            app.pageEditField.Value = app.b*(app.amax +1) + app.a + 1;

        end

        % Value changed function: dyEditField
        function dyEditFieldValueChanged(app, event)
            app.value1 = app.dyEditField.Value;

            app.SelectFileLabel.Text = 'dy changed';

            app.amax = floor(app.sizeof(1,1)/app.value1);


            app.maxrowEditField.Value = app.amax + 1;
            app.maxpageEditField.Value = (app.bmax + 1)*(app.amax + 1);
        end

        % Value changed function: dxEditField_2
        function dxEditField_2ValueChanged(app, event)
            app.value2 = app.dxEditField_2.Value;

            app.SelectFileLabel.Text = 'dx changed';

            app.bmax = floor(app.sizeof(1,2)/app.value2);


            app.maxcolumnEditField.Value = app.bmax + 1;
            app.maxpageEditField.Value = (app.bmax + 1)*(app.amax + 1);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)


            coord = get(app.UIAxes, 'CurrentPoint');
            coordseg = get(app.UIAxes2, 'CurrentPoint');
            
            app.axis1 = axis(app.UIAxes);


            %%%%%% writing and deleting

            if app.dummy == 0

                if ((app.a == -1 && app.b == 0) || (app.a == (app.amax + 1) && app.b == app.bmax))
                    if (0 < coord(1,1)) && (coord(1,1)<= app.sizeof(1,2)) && (0 < coord(1,2)) && (coord(1,2)<= app.sizeof(1,1)) && (coord(1,2) < app.axis1(1,4))
                        app.coord1 = round(coord(1,1:2));
                        app.SelectFileLabel.Text = 'coord1 write selected';
                        app.dummy = 1;
                        %bug = 1
                    else
                        if (0 < coordseg(1,1)) && (coordseg(1,1)<= app.sizeof(1,2)) && (0 < coordseg(1,2)) && (coordseg(1,2)<= app.sizeof(1,1))
                            app.coord1seg = round(coordseg(1,1:2));
                            app.dummy = 1;
                            app.SelectFileLabel.Text = 'coord1 delete selected';
                            %bug = 2
                        else
                            app.dummy = 0;
                            app.SelectFileLabel.Text = 'coord reset';
                            %bug = 3
                        end
                    end

                else

                    if (0 < coord(1,1)) && (coord(1,1)<= app.value2) && (0 <= coord(1,2)) && (coord(1,2)< app.value1) && (coord(1,2) < app.axis1(1,4))
                        
                        app.coord1 = round(coord(1,1:2));
                        app.dummy = 1;
                        app.SelectFileLabel.Text = 'coord1 write selected';
                        %bug = 4
                    else
                        if (0 < coordseg(1,1)) && (coordseg(1,1)<= app.value2) && (0 <= coordseg(1,2)) && (coordseg(1,2)< app.value1)
                            app.coord1seg = round(coordseg(1,1:2));
                            app.dummy = 1;
                            app.SelectFileLabel.Text = 'coord1 delete selected';
                            %bug = 5
                        else
                            app.dummy = 0;
                            app.SelectFileLabel.Text = 'coord reset';
                            %bug = 6
                        end
                    
                    end
                end
            else

                if ((app.a == -1 && app.b == 0) || (app.a == (app.amax + 1) && app.b == app.bmax))
                    if (0 < coord(1,1)) && (coord(1,1)<= app.sizeof(1,2)) && (0 < coord(1,2)) && (coord(1,2)<= app.sizeof(1,1)) && (coord(1,2) < app.axis1(1,4))

                        app.coord2 = round(coord(1,1:2));
                        app.SelectFileLabel.Text = 'coord2 write selected';
                        miny = min(app.coord1(1,1), app.coord2(1,1));
                        maxy = max(app.coord1(1,1), app.coord2(1,1));
                        minx = min(app.coord1(1,2), app.coord2(1,2));
                        maxx = max(app.coord1(1,2), app.coord2(1,2));

                        app.seg(minx:maxx, miny:maxy) = 1;

                        imagesc(app.UIAxes2,app.seg);
                        axis(app.UIAxes2, app.axis1);
                        

                        app.dummy = 0;
                        %bug = 7
                    else
                        if (0 < coordseg(1,1)) && (coordseg(1,1)<= app.sizeof(1,2)) && (0 < coordseg(1,2)) && (coordseg(1,2)<= app.sizeof(1,1))

                            app.coord2seg = round(coordseg(1,1:2));
                            app.SelectFileLabel.Text = 'coord2 delete selected';
                            minyseg = min(app.coord1seg(1,1), app.coord2seg(1,1));
                            maxyseg = max(app.coord1seg(1,1), app.coord2seg(1,1));
                            minxseg = min(app.coord1seg(1,2), app.coord2seg(1,2));
                            maxxseg = max(app.coord1seg(1,2), app.coord2seg(1,2));
    
                            app.seg(minxseg:maxxseg, minyseg:maxyseg) = 0;
    
                            imagesc(app.UIAxes2,app.seg);
                            axis(app.UIAxes2, app.axis1);
                            app.dummy = 0;
                            %bug = 8
                        else
                            app.dummy = 0;
                            app.SelectFileLabel.Text = 'coord reset';
                            %bug = 9
                        end
                    end


                else


                    if (0 < coord(1,1)) && (coord(1,1)<= app.value2) && (0 <= coord(1,2)) && (coord(1,2)< app.value1) && (coord(1,2) < app.axis1(1,4))

                        app.coord2 = round(coord(1,1:2));
                        app.SelectFileLabel.Text = 'coord2 write selected' ;
                        miny = min(app.coord1(1,1), app.coord2(1,1));
                        maxy = max(app.coord1(1,1), app.coord2(1,1));
                        minx = min(app.coord1(1,2), app.coord2(1,2));
                        maxx = max(app.coord1(1,2), app.coord2(1,2));


                        if app.a*app.value1 + maxx < app.sizeof(1,1)
                            if app.b*app.value2 + maxy < app.sizeof(1,2)
                                app.seg(app.a*app.value1 + minx:app.a*app.value1 + maxx, app.b*app.value2 + miny:app.b*app.value2 + maxy) = 1;
                            else
                                app.seg(app.a*app.value1 + minx:app.a*app.value1 + maxx, app.b*app.value2 + miny:end) = 1;
                            end
                        else
                            if app.b*app.value2 + maxy < app.sizeof(1,2)
                                app.seg(app.a*app.value1 + minx:end, app.b*app.value2 + miny:app.b*app.value2 + maxy) = 1;
                            else
                                app.seg(app.a*app.value1 + minx:end, app.b*app.value2 + miny:end) = 1;
                            end
                        end

                        if app.a == app.amax
                            if app.b == app.bmax
                                app.segbuff = app.seg(app.a*app.value1+1:end, app.b*app.value2+1:end);
                            else
                                app.segbuff = app.seg(app.a*app.value1+1:end, app.b*app.value2+1: (app.b + 1)*app.value2);
                            end
                        else
                            if app.b == app.bmax
                                app.segbuff = app.seg(app.a*app.value1+1:(app.a + 1)*app.value1, app.b*app.value2+1:end);
                            else
                                app.segbuff = app.seg(app.a*app.value1+1:(app.a + 1)*app.value1, app.b*app.value2+1: (app.b + 1)*app.value2);
                            end
                        end


                        imagesc(app.UIAxes2,app.segbuff);
                        axis(app.UIAxes2, app.axis1);

                        app.dummy = 0;
                        %bug = 10
                    else
                        if (0 < coordseg(1,1)) && (coordseg(1,1)<= app.value2) && (0 <= coordseg(1,2)) && (coordseg(1,2)< app.value1)
                            app.coord2seg = round(coordseg(1,1:2));
                            app.SelectFileLabel.Text = 'coord2 delete selected' ;

                            minyseg = min(app.coord1seg(1,1), app.coord2seg(1,1));
                            maxyseg = max(app.coord1seg(1,1), app.coord2seg(1,1));
                            minxseg = min(app.coord1seg(1,2), app.coord2seg(1,2));
                            maxxseg = max(app.coord1seg(1,2), app.coord2seg(1,2));


                            if app.a*app.value1 + maxxseg < app.sizeof(1,1)
                                if app.b*app.value2 + maxyseg < app.sizeof(1,2)
                                    app.seg(app.a*app.value1 + minxseg:app.a*app.value1 + maxxseg, app.b*app.value2 + minyseg:app.b*app.value2 + maxyseg) = 0;
                                else
                                    app.seg(app.a*app.value1 + minxseg:app.a*app.value1 + maxxseg, app.b*app.value2 + minyseg:end) = 0;
                                end
                            else
                                if app.b*app.value2 + maxyseg < app.sizeof(1,2)
                                    app.seg(app.a*app.value1 + minxseg:end, app.b*app.value2 + minyseg:app.b*app.value2 + maxyseg) = 0;
                                else
                                    app.seg(app.a*app.value1 + minxseg:end, app.b*app.value2 + minyseg:end) = 0;
                                end
                            end

                            if app.a == app.amax
                                if app.b == app.bmax
                                    app.segbuff = app.seg(app.a*app.value1+1:end, app.b*app.value2+1:end);
                                else
                                    app.segbuff = app.seg(app.a*app.value1+1:end, app.b*app.value2+1: (app.b + 1)*app.value2);
                                end
                            else
                                if app.b == app.bmax
                                    app.segbuff = app.seg(app.a*app.value1+1:(app.inxa + 1)*app.value1, app.b*app.value2+1:end);
                                else
                                    app.segbuff = app.seg(app.a*app.value1+1:(app.a + 1)*app.value1, app.b*app.value2+1: (app.b + 1)*app.value2);
                                end
                            end


                            imagesc(app.UIAxes2,app.segbuff);
                            axis(app.UIAxes2, app.axis1);

                            app.dummy = 0;
                            %bug = 11
                        else
                            app.dummy = 0;
                            app.SelectFileLabel.Text = 'coord reset';
                            %bug = 12
                        end
                    end

                end


            end

            %{
                    if app.coord1(1,1) < app.coord2(1,1)
                        if app.coord1(1,2) < app.coord2(1,2)
                            
                            app.seg(app.a*app.value1 + app.coord1(1,1):app.a*app.value1 + app.coord2(1,1), app.b*app.value2 + app.coord1(1,2):app.b*app.value2 + app.coord2(1,2)) = 1 - app.seg(app.a*app.value1 + app.coord1(1,1):app.a*app.value1 + app.coord2(1,1), app.b*app.value2 + app.coord1(1,2):app.b*app.value2 + app.coord2(1,2));
                            
                        else
                            app.seg(app.a*app.value1 + app.coord1(1,1):app.a*app.value1 + app.coord2(1,1), app.b*app.value2 + app.coord2(1,2):app.b*app.value2 + app.coord1(1,2)) = 1 - app.seg(app.a*app.value1 + app.coord1(1,1):app.a*app.value1 + app.coord2(1,1), app.b*app.value2 + app.coord2(1,2):app.b*app.value2 + app.coord1(1,2));
                        end
                    else
                        if app.coord1(1,2) < app.coord2(1,2)
    
                            app.seg(app.a*app.value1 + app.coord2(1,1):app.a*app.value1 + app.coord1(1,1), app.b*app.value2 + app.coord1(1,2):app.b*app.value2 + app.coord2(1,2)) = 1 - app.seg(app.a*app.value1 + app.coord2(1,1):app.a*app.value1 + app.coord1(1,1), app.b*app.value2 + app.coord1(1,2):app.b*app.value2 + app.coord2(1,2));
                            
                        else
                            app.seg(app.a*app.value1 + app.coord2(1,1):app.a*app.value1 + app.coord1(1,1), app.b*app.value2 + app.coord2(1,2):app.b*app.value2 + app.coord1(1,2)) = 1 - app.seg(app.a*app.value1 + app.coord2(1,1):app.a*app.value1 + app.coord1(1,1), app.b*app.value2 + app.coord2(1,2):app.b*app.value2 + app.coord1(1,2));
                        end
                    end
            %}

        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)

            p = app.seg;
            P = app.carry;
            save(app.savedir,'p')

            if isempty(app.seg)
                app.SelectFileLabel.Text = 'No data to save';
            else
                app.SelectFileLabel.Text = 'Data saved';
            end

            if exist(app.filedir,'file') ~= 2
                save(app.filedir,'P')
            end

        end

        % Value changed function: rowEditField
        function rowEditFieldValueChanged(app, event)
            app.a = app.rowEditField.Value - 1;


        end

        % Value changed function: columnEditField
        function columnEditFieldValueChanged(app, event)
            app.b = app.columnEditField.Value - 1;

        end

        % Button pushed function: defaultwithButton
        function defaultwithButtonPushed(app, event)


            noise=mean(app.carry(:));

            segdef = noise + app.minsnr < app.carry;
            app.seg = segdef;
            app.a = -1;
            app.b = 0;
            imagesc(app.UIAxes2, app.seg);
            axis(app.UIAxes2, "tight");

            imagesc(app.UIAxes,app.carry);
            axis(app.UIAxes, "tight");


        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.minsnr = app.EditField.Value;
            
        end

        % Button pushed function: mainButton
        function mainButtonPushed(app, event)

            if ((app.a == -1 && app.b == 0) || (app.a == (app.amax + 1) && app.b == app.bmax))
                imagesc(app.UIAxes,app.carry);
                axis(app.UIAxes, "tight");
                imagesc(app.UIAxes2,app.seg);
                axis(app.UIAxes2, "tight");
            else
                imagesc(app.UIAxes,app.buffer);
                axis(app.UIAxes, "tight");
                imagesc(app.UIAxes2,app.segbuff);
                axis(app.UIAxes2, "tight");
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.initButton = uibutton(app.LeftPanel, 'push');
            app.initButton.ButtonPushedFcn = createCallbackFcn(app, @initButtonPushed, true);
            app.initButton.Text = 'init';
            app.dxEditField_2Label = uilabel(app.LeftPanel);
            app.dxEditField_2Label.Text = 'dx';
            app.dxEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.dxEditField_2.ValueChangedFcn = createCallbackFcn(app, @dxEditField_2ValueChanged, true);
            app.dxEditField_2.Value = 800;
            app.dyEditFieldLabel = uilabel(app.LeftPanel);
            app.dyEditFieldLabel.Text = 'dy';
            app.columnLabel = uilabel(app.LeftPanel);
            app.columnLabel.Text = 'column:';
            app.columnEditField = uieditfield(app.LeftPanel, 'numeric');
            app.columnEditField.ValueChangedFcn = createCallbackFcn(app, @columnEditFieldValueChanged, true);
            app.columnEditField.Editable = 'off';
            app.rowLabel = uilabel(app.LeftPanel);
            app.rowLabel.Text = 'row:';
            app.rowEditField = uieditfield(app.LeftPanel, 'numeric');
            app.rowEditField.ValueChangedFcn = createCallbackFcn(app, @rowEditFieldValueChanged, true);
            app.rowEditField.Editable = 'off';
            app.dyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dyEditField.ValueChangedFcn = createCallbackFcn(app, @dyEditFieldValueChanged, true);
            app.dyEditField.Value = 125;
            app.saveButton = uibutton(app.LeftPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.maxrowEditField = uieditfield(app.LeftPanel, 'numeric');
            app.maxrowEditField.Editable = 'off';
            app.maxrowEditField.Enable = 'off';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '/';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '/';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '/';
            app.maxpageEditField = uieditfield(app.LeftPanel, 'numeric');
            app.maxpageEditField.Editable = 'off';
            app.maxpageEditField.Enable = 'off';
            app.pageEditFieldLabel = uilabel(app.LeftPanel);
            app.pageEditFieldLabel.Text = 'page';
            app.pageEditField = uieditfield(app.LeftPanel, 'numeric');
            app.pageEditField.Editable = 'off';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 20;
            app.defaultwithButton = uibutton(app.LeftPanel, 'push');
            app.defaultwithButton.ButtonPushedFcn = createCallbackFcn(app, @defaultwithButtonPushed, true);
            app.defaultwithButton.Text = 'default with:';
            app.SelectFileLabel = uilabel(app.LeftPanel);
            app.SelectFileLabel.Text = ' Select File';
            app.prevButton = uibutton(app.LeftPanel, 'push');
            app.prevButton.ButtonPushedFcn = createCallbackFcn(app, @prevButtonPushed, true);
            app.prevButton.Text = 'prev';
            app.nextButton = uibutton(app.LeftPanel, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.mainButton = uibutton(app.LeftPanel, 'push');
            app.mainButton.ButtonPushedFcn = createCallbackFcn(app, @mainButtonPushed, true);
            app.mainButton.Text = 'main';
            app.maxcolumnEditField = uieditfield(app.LeftPanel, 'numeric');
            app.maxcolumnEditField.Editable = 'off';
            app.maxcolumnEditField.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segment_v1_nomaxclmn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end